classdef COMPLETE_PDF_REPORT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        StatusLabel             matlab.ui.control.Label
        GenerateReportButton    matlab.ui.control.Button
        CompletePDFReportLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        ExcelPath % Description
    end
    
    methods (Access = public)
        
           function initialize(app, excelPath)
            app.ExcelPath = excelPath;
            app.StatusLabel.Text = '✅ Excel file path received. Ready to generate report.';
           end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateReportButton
        function GenerateReportButtonPushed(app, event)
            

               try
                    generateCompleteReport(app.ExcelPath);  % presupunând că ai salvat path-ul la import
               catch ME
                    uialert(app.UIFigure, ['❌ Eroare generare raport: ', ME.message], 'Eroare');
               end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CompletePDFReportLabel = uilabel(app.UIFigure);
            app.CompletePDFReportLabel.Text = 'Complete PDF Report';
            app.GenerateReportButton = uibutton(app.UIFigure, 'push');
            app.GenerateReportButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButtonPushed, true);
            app.GenerateReportButton.Text = 'Generate Report';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Waiting for input...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = COMPLETE_PDF_REPORT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end