classdef DATA_CHART < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GenerateChartButton       matlab.ui.control.Button
        StatusLabel               matlab.ui.control.Label
        ExportPDFButton           matlab.ui.control.Button
        ExportPNGButton           matlab.ui.control.Button
        ChartTypeDropDown         matlab.ui.control.DropDown
        ChartTypeDropDownLabel    matlab.ui.control.Label
        YAxisDropDown             matlab.ui.control.DropDown
        YaxisColumnDropDownLabel  matlab.ui.control.Label
        XAxisDropDown             matlab.ui.control.DropDown
        XaxisColumnDropDownLabel  matlab.ui.control.Label
        SheetDropDown             matlab.ui.control.DropDown
        SheetDropDownLabel        matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        ExcelPath char
    end


    methods (Access = public)
        
        function initialize(app, excelPath)
            app.ExcelPath = excelPath;

            % Încarcă prima foaie
            [~, sheets] = xlsfinfo(excelPath);
            app.SheetDropDown.Items = sheets;
            app.SheetDropDown.Value = sheets{1};

            app.ChartTypeDropDown.Items = {'Line', 'Bar', 'Scatter', 'Histogram'};
            app.ChartTypeDropDown.Value = 'Line';  % implicit


            % Populează dropdownurile pentru coloane
            populateColumnDropdowns(app, sheets{1});

            % Status inițial
            app.StatusLabel.Text = "✅ Excel loaded. Choose chart options.";
        end
    end


    methods (Access = private)
        
        function populateColumnDropdowns(app, sheetName)
            try
                data = readtable(app.ExcelPath, 'Sheet', sheetName);
                colNames = data.Properties.VariableNames;

                app.XAxisDropDown.Items = colNames;
                app.XAxisDropDown.Value = colNames{1};

                app.YAxisDropDown.Items = colNames;
                app.YAxisDropDown.Value = colNames{2};
            catch
                app.StatusLabel.Text = ['❌ Failed to read ', sheetName];
            end
        end


        function SheetDropDownValueChanged(app, event)
            selectedSheet = app.SheetDropDown.Value;
            populateColumnDropdowns(app, selectedSheet);
            app.StatusLabel.Text = ['✅ Sheet "', selectedSheet, '" loaded.'];
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateChartButton
        function GenerateChartButtonPushed2(app, event)
            try
                % Citește foaia și coloanele selectate
                sheetName = app.SheetDropDown.Value;
                xCol = app.XAxisDropDown.Value;
                yCol = app.YAxisDropDown.Value;
                chartType = app.ChartTypeDropDown.Value;

                % Încarcă datele din fișier
                data = readtable(app.ExcelPath, 'Sheet', sheetName);
                x = data.(xCol);
                y = data.(yCol);

                % Curăță axa
                cla(app.UIAxes)

                % Generează graficul ales
                switch chartType
                    case 'Line'
                        plot(app.UIAxes, x, y, '-o');
                    case 'Bar'
                        bar(app.UIAxes, x, y);
                    case 'Scatter'
                        scatter(app.UIAxes, x, y, 'filled');
                    case 'Histogram'
                        histogram(app.UIAxes, y);
                end

                % Etichete și titlu
                app.UIAxes.Title.String = sprintf('%s chart: %s vs %s', chartType, yCol, xCol);
                app.UIAxes.XLabel.String = xCol;
                app.UIAxes.YLabel.String = yCol;

                % Mesaj status
                app.StatusLabel.Text = sprintf('✅ Generated %s chart from %s.', chartType, sheetName);
            catch
                app.StatusLabel.Text = '❌ Error generating chart.';
            end
        end

        % Button pushed function: ExportPNGButton
        function GenerateChartButtonPushed3(app, event)
            [file, path] = uiputfile({'*.png'}, 'Save Chart as PNG');
            if isequal(file, 0)
                app.StatusLabel.Text = '⚠ Export cancelled.';
                return;
            end

            exportPath = fullfile(path, file);
            try
                 % Salvează imaginea axei
                 exportgraphics(app.UIAxes, exportPath, 'Resolution', 300);
                 app.StatusLabel.Text = ['✅ Chart exported to PNG: ', file];
            catch
                app.StatusLabel.Text = '❌ Failed to export PNG.';
            end
        end

        % Button pushed function: ExportPDFButton
        function ExportPDFButtonPushed(app, event)
            [file, path] = uiputfile({'*.pdf'}, 'Save Chart as PDF');
            if isequal(file, 0)
               app.StatusLabel.Text = '⚠ Export cancelled.';
               return;
            end

            exportPath = fullfile(path, file);
            try
             % Salvează imaginea axei în PDF
             exportgraphics(app.UIAxes, exportPath, 'ContentType', 'vector');
             app.StatusLabel.Text = ['✅ Chart exported to PDF: ', file];
            catch
             app.StatusLabel.Text = '❌ Failed to export PDF.';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SheetDropDownLabel = uilabel(app.UIFigure);
            app.SheetDropDownLabel.Text = 'Sheet   DropDown';
            app.SheetDropDown = uidropdown(app.UIFigure);
            app.XaxisColumnDropDownLabel = uilabel(app.UIFigure);
            app.XaxisColumnDropDownLabel.Text = 'X-axis Column';
            app.XAxisDropDown = uidropdown(app.UIFigure);
            app.YaxisColumnDropDownLabel = uilabel(app.UIFigure);
            app.YaxisColumnDropDownLabel.Text = 'Y-axis Column';
            app.YAxisDropDown = uidropdown(app.UIFigure);
            app.ChartTypeDropDownLabel = uilabel(app.UIFigure);
            app.ChartTypeDropDownLabel.Text = 'Chart Type';
            app.ChartTypeDropDown = uidropdown(app.UIFigure);
            app.ExportPNGButton = uibutton(app.UIFigure, 'push');
            app.ExportPNGButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateChartButtonPushed3, true);
            app.ExportPNGButton.Text = 'Export PNG';
            app.ExportPDFButton = uibutton(app.UIFigure, 'push');
            app.ExportPDFButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPDFButtonPushed, true);
            app.ExportPDFButton.Text = 'Export PDF';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Waiting for input...';
            app.GenerateChartButton = uibutton(app.UIFigure, 'push');
            app.GenerateChartButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateChartButtonPushed2, true);
            app.GenerateChartButton.Text = 'Generate Chart';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DATA_CHART

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end