classdef DATA_SUMMARY < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SummaryStatusLabel        matlab.ui.control.Label
        ExportasExcelButton       matlab.ui.control.Button
        ExportasCSVButton         matlab.ui.control.Button
        SummaryTable              matlab.ui.control.Table
        SheetDropDown             matlab.ui.control.DropDown
        SelectSheetDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        ExcelPath char
    end
    
    methods (Access = public)
        
        function initialize(app, excelPath)
         app.ExcelPath = excelPath;

         % Obține foile din fișier
         [~, sheets] = xlsfinfo(excelPath);
         app.SheetDropDown.Items = sheets;

         % Setare default pe prima foaie
         app.SheetDropDown.Value = sheets{1};

         % Încarcă sumarul pentru prima foaie
         updateSummary(app, sheets{1});

         % Status
         app.SummaryStatusLabel.Text = '✅ Summary generated.';
        end

        
    end
    
    methods (Access = private)
        
        
        function updateSummary(app, sheetName)
    try
        data = readtable(app.ExcelPath, 'Sheet', sheetName);
    catch
        app.SummaryStatusLabel.Text = ['❌ Failed to load ', sheetName];
        return;
    end

    vars = data.Properties.VariableNames;
    summary = [];

    for i = 1:numel(vars)
        col = data.(vars{i});

        if isnumeric(col)
            colData = col(~isnan(col));
            colSummary = {
                vars{i}, ...
                class(col), ...
                sum(~ismissing(col)), ...
                sum(ismissing(col)), ...
                numel(unique(colData)), ...
                mode(colData), ...
                min(colData), ...
                max(colData), ...
                mean(colData), ...
                median(colData), ...
                std(colData), ...
                skewness(colData), ...
                kurtosis(colData), ...
                range(colData), ...
                100 * sum(ismissing(col)) / numel(col), ...
                all(colData == colData(1)) ...
            };
        else
            colSummary = {
                vars{i}, ...
                class(col), ...
                sum(~ismissing(col)), ...
                sum(ismissing(col)), ...
                numel(unique(col)), ...
                mode(categorical(col)), ...
                '', '', '', '', '', '', '', '', ...
                100 * sum(ismissing(col)) / numel(col), ...
                false ...
            };
        end

        summary = [summary; colSummary]; %#ok<AGROW>
    end

    % Definire cap de tabel
    headers = {
        'Column Name', 'Data Type', 'Non-Missing Count', 'Missing Count', ...
        'Unique Values', 'Most Frequent Value', ...
        'Min', 'Max', 'Mean', 'Median', 'Std. Deviation', ...
        'Skewness', 'Kurtosis', 'Range', '% Missing', 'Is Constant'};

    app.SummaryTable.Data = summary;
    app.SummaryTable.ColumnName = headers;
end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SheetDropDown
        function SheetDropDownValueChanged(app, event)
            sheetName = app.SheetDropDown.Value;
            updateSummary(app, sheetName);
            app.SummaryStatusLabel.Text = ['Summary updated for "', sheetName, '"'];
            
        end

        % Button pushed function: ExportasCSVButton
        function ExportasCSVButtonPushed(app, event)
            [file, path] = uiputfile('*.csv', 'Save Summary as CSV');
            if isequal(file,0), return; end
        
            try
                T = cell2table(app.SummaryTable.Data, ...
                    'VariableNames', app.SummaryTable.ColumnName);
                writetable(T, fullfile(path, file));
                app.SummaryStatusLabel.Text = '✅ Exported as CSV.';
            catch
                app.SummaryStatusLabel.Text = '❌ Failed to export as CSV.';
            end
        end

        % Button pushed function: ExportasExcelButton
        function ExportasExcelButtonPushed(app, event)
            [file, path] = uiputfile('*.xlsx', 'Save Summary as Excel');
            if isequal(file,0), return; end
        
            try
                T = cell2table(app.SummaryTable.Data, ...
                    'VariableNames', app.SummaryTable.ColumnName);
                writetable(T, fullfile(path, file));
                app.SummaryStatusLabel.Text = '✅ Exported as Excel.';
            catch
                app.SummaryStatusLabel.Text = '❌ Failed to export as Excel.';
            end
        end

        % Callback function: not associated with a component
        function ExportasPDFButtonPushed(app, event)
         %scoasa
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectSheetDropDownLabel = uilabel(app.UIFigure);
            app.SelectSheetDropDownLabel.Text = 'Select Sheet';
            app.SheetDropDown = uidropdown(app.UIFigure);
            app.SheetDropDown.ValueChangedFcn = createCallbackFcn(app, @SheetDropDownValueChanged, true);
            app.SummaryTable = uitable(app.UIFigure);
            app.SummaryTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SummaryTable.RowName = {};
            app.ExportasCSVButton = uibutton(app.UIFigure, 'push');
            app.ExportasCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportasCSVButtonPushed, true);
            app.ExportasCSVButton.Text = 'Export as CSV';
            app.ExportasExcelButton = uibutton(app.UIFigure, 'push');
            app.ExportasExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportasExcelButtonPushed, true);
            app.ExportasExcelButton.Text = 'Export as Excel';
            app.SummaryStatusLabel = uilabel(app.UIFigure);
            app.SummaryStatusLabel.Text = 'Status : Waiting for sheet selection...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DATA_SUMMARY

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end