classdef EDIT_IMPORTED_DATA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        RedoButton                matlab.ui.control.Button
        UndoButton                matlab.ui.control.Button
        StatusLabel               matlab.ui.control.Label
        EditSwitch                matlab.ui.control.Switch
        EditModeSwitchLabel       matlab.ui.control.Label
        SheetDropDown             matlab.ui.control.DropDown
        SelectSheetDropDownLabel  matlab.ui.control.Label
        CancelButton              matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        UITableContextMenuEdit    matlab.ui.container.ContextMenu
        RenameColumnMenu          matlab.ui.container.Menu
        DeleteRowMenu             matlab.ui.container.Menu
        DeleteColumnMenu          matlab.ui.container.Menu
    end

    
    properties (Access = public)
         ExcelPath char
         ImportedData table  % datele curente din tabel
    end
    
    properties (Access = private)
      UndoStack cell    % stivă pentru undo
      RedoStack cell    % stivă pentru redo
      OriginalData table
      LastSelectedColumn = 1
      LastClickedCell
    end

  
    methods (Access = public)
     function initialize(app, excelPath)
        app.UndoStack = {};
        app.RedoStack = {};
        app.ExcelPath = excelPath;

        % Obține toate foile din fișier
        [~, sheets] = xlsfinfo(excelPath);
        app.SheetDropDown.Items = sheets;

        % Selectează prima foaie și încarcă datele
        app.SheetDropDown.Value = sheets{1};
        data = readtable(excelPath, 'Sheet', sheets{1});
        app.UITable.Data = data;
        app.UITable.ColumnName = data.Properties.VariableNames;

        defaultHeaders = startsWith(data.Properties.VariableNames, 'Var');
        if all(defaultHeaders)
           for i = 1:width(data)
               data.Properties.VariableNames{i} = ['Column' num2str(i)];
           end
        end

        % Stocare temporară în caz de salvare
        app.ImportedData = data;
        app.OriginalData = data;  % salvează și anteturile actualizate

        % Modul implicit: non-editabil
        app.UITable.ColumnEditable = false(1, width(data));
        app.EditSwitch.Value = 'Off';

        % Reset statusF
        app.StatusLabel.Text = 'Status : Sheet loaded successfully';

        app.UIFigure.WindowButtonDownFcn = @(src, event) tableRightClick(app, src, event);
     end
        
        function pushUndo(app)
          state.Data = app.UITable.Data;
          state.ColumnName = app.UITable.ColumnName;
          app.UndoStack{end+1} = state;
          app.RedoStack = {};
        end
    end

    
    methods (Access = private)
        
        function tableRightClick(app , src, event)
           % Preluăm poziția clickului în UIFigure
        figPoint = app.UIFigure.CurrentPoint;

        % Poziția și dimensiunile tabelului
        tablePos = app.UITable.Position;

        % Verificăm dacă clickul a fost în interiorul tabelului
        if figPoint(1) < tablePos(1) || figPoint(1) > tablePos(1) + tablePos(3) || ...
           figPoint(2) < tablePos(2) || figPoint(2) > tablePos(2) + tablePos(4)
           return;  % Clickul a fost în afara tabelului
        end

        % Convertim coordonatele din figura globală în coordonate tabel
        relX = figPoint(1) - tablePos(1);
        relY = figPoint(2) - tablePos(2);

        % Estimăm înălțimea și lățimea celulelor
        numRows = height(app.UITable.Data);
        numCols = width(app.UITable.Data);

        rowHeight = tablePos(4) / numRows;
        colWidth = tablePos(3) / numCols;

        % Calculăm indicele rândului și coloanei
        rowIndex = numRows - floor(relY / rowHeight);
        colIndex = floor(relX / colWidth) + 1;

        % Validăm și setăm selecția
        if rowIndex >= 1 && rowIndex <= numRows && ...
           colIndex >= 1 && colIndex <= numCols
           app.UITable.Selection = [rowIndex, colIndex];
           app.LastSelectedColumn = colIndex;
        end
       end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SheetDropDown
        function SheetDropDownValueChanged(app, event)
              selectedSheet = app.SheetDropDown.Value;

              try
               % Încarcă datele din sheet-ul ales
               newData = readtable(app.ExcelPath, 'Sheet', selectedSheet);
               if isempty(newData.Properties.VariableNames) || any(contains(newData.Properties.VariableNames, {'Var', ''}))
                  for i = 1:width(newData)
                      newData.Properties.VariableNames{i} = ['Column' num2str(i)];
                  end
               end


               % Actualizează tabela
               app.UITable.Data = newData;

               % Dacă e pe edit, păstrează editabilitatea
               isEditable = strcmp(app.EditSwitch.Value, 'On');
               app.UITable.ColumnEditable = repmat(isEditable, 1, width(newData));

               % Actualizează statusul
               app.StatusLabel.Text = ['Status : Loaded sheet "', selectedSheet, '"'];
              catch
               app.StatusLabel.Text = ['⚠️ Error: Unable to load "', selectedSheet, '"'];
              end
        end

        % Value changed function: EditSwitch
        function EditSwitchValueChanged(app, event)
                isEditable = strcmp(app.EditSwitch.Value, 'On');
    
                % Fă toate coloanele editabile sau nu
                app.UITable.ColumnEditable = repmat(isEditable, 1, width(app.UITable.Data));
    
                % Actualizează statusul
                if isEditable
                   app.StatusLabel.Text = 'Status : Edit mode ON';
                else
                   app.StatusLabel.Text = 'Status : Edit mode OFF';
                end
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            choice = questdlg('Do you want to overwrite the Excel file with current changes?', ...
                      'Confirm Save', 'Yes', 'No', 'No');

            if strcmp(choice, 'Yes')
               try
                % Scrie în foaia curentă
                selectedSheet = app.SheetDropDown.Value;
                data = app.UITable.Data;

                writetable(data, app.ExcelPath, 'Sheet', selectedSheet);

                % Actualizare status
                app.StatusLabel.Text = ['✅ Changes saved to "', selectedSheet, '" successfully.'];

                % Salvează local în caz că fereastra principală folosește aceste date
                app.ImportedData = data;

               catch ME
                app.StatusLabel.Text = ['❌ Error saving changes: ' ME.message];
               end
            else
               app.StatusLabel.Text = '⚠️ Save cancelled by user.';
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            choice = questdlg('Discard all changes and reload the original sheet?', ...
                      'Cancel Editing', 'Yes', 'No', 'No');

            if strcmp(choice, 'Yes')
              try
                selectedSheet = app.SheetDropDown.Value;

                % Reîncarcă datele din fișier
                originalData = readtable(app.ExcelPath, 'Sheet', selectedSheet);
                app.UITable.Data = originalData;

                % Resetare stare editabilă
                isEditable = strcmp(app.EditSwitch.Value, 'On');
                app.UITable.ColumnEditable = repmat(isEditable, 1, width(originalData));

                % Status update
                app.StatusLabel.Text = ['↩️ Changes discarded. Reloaded "', selectedSheet, '".'];

              catch ME
                app.StatusLabel.Text = ['❌ Error reloading data: ' ME.message];
              end
           else
             app.StatusLabel.Text = '⚠️ Cancel action aborted.';
           end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            % Salvează starea curentă pentru Undo
            app.UndoStack{end+1} = app.UITable.Data;
    
            % Golește Redo când se face o modificare nouă
            app.RedoStack = {};

            % Actualizează status
            app.StatusLabel.Text = 'Status: Change recorded (Undo available)';
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            if isempty(app.UndoStack)
               app.StatusLabel.Text = '⚠️ Nothing to undo.';
               return;
            end

            curr.Data = app.UITable.Data;
            curr.ColumnName = app.UITable.ColumnName;
            app.RedoStack{end+1} = curr;

            prev = app.UndoStack{end};
            app.UITable.Data = prev.Data;
            app.UITable.ColumnName = prev.ColumnName;

            app.UndoStack(end) = [];

            app.StatusLabel.Text = '↩️ Undo performed.';
        end

        % Button pushed function: RedoButton
        function RedoButtonPushed(app, event)
            if isempty(app.RedoStack)
               app.StatusLabel.Text = '⚠️ Nothing to redo.';
               return;
            end

            curr.Data = app.UITable.Data;
            curr.ColumnName = app.UITable.ColumnName;
            app.UndoStack{end+1} = curr;

            redoState = app.RedoStack{end};
            app.UITable.Data = redoState.Data;
            app.UITable.ColumnName = redoState.ColumnName;

            app.RedoStack(end) = [];

            app.StatusLabel.Text = '🔁 Redo performed.';
        end

        % Menu selected function: RenameColumnMenu
        function RenameColumnMenuSelected(app, event)
                app.pushUndo();

                colIndex = app.LastSelectedColumn;
            
                if isempty(colIndex) || colIndex > width(app.UITable.Data)
                    uialert(app.UIFigure, 'Please select a cell in the column you want to rename.', 'No Selection');
                    return;
                end
            
                defaultName = app.UITable.Data.Properties.VariableNames{colIndex};
                newName = inputdlg('Enter new column name:', ...
                    'Rename Column', [1 50], {defaultName});
            
                if isempty(newName)
                    return;
                end
            
                validNewName = matlab.lang.makeValidName(newName{1});
                if any(strcmp(validNewName, app.UITable.Data.Properties.VariableNames))
                    uialert(app.UIFigure, 'Column name already exists.', 'Duplicate Name');
                    return;
                end
            
                app.UITable.Data.Properties.VariableNames{colIndex} = validNewName;
                app.UITable.ColumnName = app.UITable.Data.Properties.VariableNames;
            
                % 🔧 Actualizează UI cu noile nume
                app.UITable.ColumnName = app.UITable.Data.Properties.VariableNames;
            
                app.StatusLabel.Text = ['📝 Renamed column ', defaultName, ' → ', validNewName];
        end

        % Menu selected function: DeleteRowMenu
        function DeleteRowMenuSelected(app, event)
             app.pushUndo();
             indices = app.UITable.Selection;
             if isempty(indices)
                uialert(app.UIFigure, 'Please select a row to delete.', 'No Selection');
                return;
             end

             data = app.UITable.Data;
             rowToDelete = indices(1);

             if height(data) == 1
                uialert(app.UIFigure, 'Cannot delete the last row.', 'Operation Blocked');
                return;
             end

            data(rowToDelete, :) = [];
            app.UITable.Data = data;
        end

        % Menu selected function: DeleteColumnMenu
        function DeleteColumnMenuSelected(app, event)
            app.pushUndo();
            indices = app.UITable.Selection;
            if isempty(indices)
               uialert(app.UIFigure, 'Please select a column to delete.', 'No Selection');
               return;
            end

            data = app.UITable.Data;
            colToDelete = indices(2);

            if width(data) == 1
               uialert(app.UIFigure, 'Cannot delete the last column.', 'Operation Blocked');
               return;
            end

           data(:, colToDelete) = [];
           app.UITable.Data = data;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save Changes';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SelectSheetDropDownLabel = uilabel(app.UIFigure);
            app.SelectSheetDropDownLabel.Text = 'Select Sheet ';
            app.SheetDropDown = uidropdown(app.UIFigure);
            app.SheetDropDown.ValueChangedFcn = createCallbackFcn(app, @SheetDropDownValueChanged, true);
            app.EditModeSwitchLabel = uilabel(app.UIFigure);
            app.EditModeSwitchLabel.Text = 'Edit Mode';
            app.EditSwitch = uiswitch(app.UIFigure, 'slider');
            app.EditSwitch.ValueChangedFcn = createCallbackFcn(app, @EditSwitchValueChanged, true);
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status : Nothing modified';
            app.UndoButton = uibutton(app.UIFigure, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            app.RedoButton = uibutton(app.UIFigure, 'push');
            app.RedoButton.ButtonPushedFcn = createCallbackFcn(app, @RedoButtonPushed, true);
            app.RedoButton.Text = 'Redo';
            app.UITableContextMenuEdit = uicontextmenu(app.UIFigure);
            app.RenameColumnMenu = uimenu(app.UITableContextMenuEdit);
            app.RenameColumnMenu.MenuSelectedFcn = createCallbackFcn(app, @RenameColumnMenuSelected, true);
            app.RenameColumnMenu.Text = 'Rename Column';
            app.DeleteRowMenu = uimenu(app.UITableContextMenuEdit);
            app.DeleteRowMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteRowMenuSelected, true);
            app.DeleteRowMenu.Text = 'Delete Row';
            app.DeleteColumnMenu = uimenu(app.UITableContextMenuEdit);
            app.DeleteColumnMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteColumnMenuSelected, true);
            app.DeleteColumnMenu.Text = 'Delete Column';
            % Assign app.UITableContextMenuEdit
            app.UITable.ContextMenu = app.UITableContextMenuEdit;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EDIT_IMPORTED_DATA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end