classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        v10AndreiMihaiNecsulea2025Label  matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        ImportandanalyzeExcelfilesautomaticallywithPDFvisualizationandreportLabel  matlab.ui.control.Label
        StatusLabel                    matlab.ui.control.Label
        IMPORTDATAInputanxlsxorxlsfileButton  matlab.ui.control.Button
        DATASUMMARYButton              matlab.ui.control.Button
        AUTOMATIONOFDATAANALYSISLabel  matlab.ui.control.Label
        EDITIMPORTEDDATAButton         matlab.ui.control.Button
        DATACHARTButton                matlab.ui.control.Button
        COMPLETEPDFREPORTButton        matlab.ui.control.Button
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = public)
         ExcelFilePath char
         ImportedData table
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IMPORTDATAInputanxlsxorxlsfileButton
        function IMPORTDATAInputanxlsxorxlsfileButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.xlsx;*.xls', 'Excel Files'}, 'Select Excel File');
            if isequal(filename, 0)
               % Dacă utilizatorul a închis fereastra de selecție
               app.StatusLabel.Text = 'No file selected.';
               app.StatusLabel.FontColor = [0.6 0.6 0.6]; % gri
               app.StatusLabel.FontSize = 16;
               return;
            end

            fullpath = fullfile(pathname, filename);

            try
                % Citește fișierul Excel
                data = readtable(fullpath);

                % Salvează datele într-o proprietate (dacă ai creat-o)
                app.ImportedData = data;
                app.ExcelFilePath = fullpath;

                % Afișează mesaj de succes
                app.StatusLabel.Text = ['✔ File loaded: ', filename];
                app.StatusLabel.FontColor = [0 1 0]; % verde
                app.StatusLabel.FontSize = 16;

            catch
                app.StatusLabel.Text = '❌ Error loading file!';
                app.StatusLabel.FontColor = [1 0 0]; % roșu
                app.StatusLabel.FontSize = 16;
            end

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
          msgbox(['This application imports Excel/CSV files and automates data analysis.', ...
        newline, 'You can view graphs, edit data, and generate PDF reports.'], ...
        'Info', 'help');
        end

        % Button pushed function: EDITIMPORTEDDATAButton
        function EDITIMPORTEDDATAButtonPushed(app, event)
            if isempty(app.ImportedData)
               uialert(app.UIFigure, 'No data loaded. Please import a file first.', 'Error');
               return;
            end

            try
             % Deschide aplicația pop-up pentru editare
             editApp = EDIT_IMPORTED_DATA;

             % Transmite calea fișierului Excel
             excelPath = app.ExcelFilePath;  % ← Asigură-te că ai salvat acest path când ai importat

             % Inițializează aplicația de editare
             editApp.initialize(excelPath);

            catch ME
             uialert(app.UIFigure, ['Failed to open editor: ', ME.message], 'Error');
            end
        end

        % Button pushed function: DATASUMMARYButton
        function DATASUMMARYButtonPushed(app, event)
           if isempty(app.ImportedData)
              uialert(app.UIFigure, 'No data loaded. Please import a file first.', 'Error');
              return;
           end

           try
             summaryApp = DATA_SUMMARY;
             excelPath = app.ExcelFilePath;
             summaryApp.initialize(excelPath);
           catch ME
             uialert(app.UIFigure, ['Failed to open summary: ', ME.message], 'Error');
           end
        end

        % Button pushed function: DATACHARTButton
        function DATACHARTButtonPushed(app, event)
            if isempty(app.ImportedData)
                uialert(app.UIFigure, 'No data loaded. Please import a file first.', 'Error');
                return;
            end

            try
                chartApp = DATA_CHART;
                excelPath = app.ExcelFilePath;
                chartApp.initialize(excelPath);
            catch ME
                uialert(app.UIFigure, ['Failed to open chart: ', ME.message], 'Error');
            end
        end

        % Button pushed function: COMPLETEPDFREPORTButton
        function COMPLETEPDFREPORTButtonPushed(app, event)
            if isempty(app.ImportedData)
                uialert(app.UIFigure, 'No file loaded. Please import a file first.', 'Error');
                return;
            end

            try
                reportApp = COMPLETE_PDF_REPORT;  % Creează fereastra
                excelPath = app.ExcelFilePath;
                reportApp.initialize(excelPath);  % Transmite calea fișierului Excel
            catch ME
                uialert(app.UIFigure, ['❌ Failed to open report window: ' ME.message], 'Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'sky');
            app.UIFigure.Name = 'MATLAB App';
            app.COMPLETEPDFREPORTButton = uibutton(app.UIFigure, 'push');
            app.COMPLETEPDFREPORTButton.ButtonPushedFcn = createCallbackFcn(app, @COMPLETEPDFREPORTButtonPushed, true);
            app.COMPLETEPDFREPORTButton.Tooltip = {'Generate PDF Report after data import'};
            app.COMPLETEPDFREPORTButton.Text = 'COMPLETE PDF REPORT';
            app.DATACHARTButton = uibutton(app.UIFigure, 'push');
            app.DATACHARTButton.ButtonPushedFcn = createCallbackFcn(app, @DATACHARTButtonPushed, true);
            app.DATACHARTButton.Tooltip = {'Generate Chart after data import'};
            app.DATACHARTButton.Text = 'DATA CHART ';
            app.EDITIMPORTEDDATAButton = uibutton(app.UIFigure, 'push');
            app.EDITIMPORTEDDATAButton.ButtonPushedFcn = createCallbackFcn(app, @EDITIMPORTEDDATAButtonPushed, true);
            app.EDITIMPORTEDDATAButton.Tooltip = {'Edit data after data import'};
            app.EDITIMPORTEDDATAButton.Text = 'EDIT IMPORTED DATA';
            app.AUTOMATIONOFDATAANALYSISLabel = uilabel(app.UIFigure);
            app.AUTOMATIONOFDATAANALYSISLabel.Text = 'AUTOMATION OF DATA ANALYSIS';
            app.DATASUMMARYButton = uibutton(app.UIFigure, 'push');
            app.DATASUMMARYButton.ButtonPushedFcn = createCallbackFcn(app, @DATASUMMARYButtonPushed, true);
            app.DATASUMMARYButton.Tooltip = {'Generate summary of data after data import'};
            app.DATASUMMARYButton.Text = 'DATA SUMMARY';
            app.IMPORTDATAInputanxlsxorxlsfileButton = uibutton(app.UIFigure, 'push');
            app.IMPORTDATAInputanxlsxorxlsfileButton.ButtonPushedFcn = createCallbackFcn(app, @IMPORTDATAInputanxlsxorxlsfileButtonPushed, true);
            app.IMPORTDATAInputanxlsxorxlsfileButton.Tooltip = {'Input an Excel file'};
            app.IMPORTDATAInputanxlsxorxlsfileButton.Text = 'IMPORT DATA - Input an (*.xlsx) or (*.xls) file';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'No file slected.';
            app.ImportandanalyzeExcelfilesautomaticallywithPDFvisualizationandreportLabel = uilabel(app.UIFigure);
            app.ImportandanalyzeExcelfilesautomaticallywithPDFvisualizationandreportLabel.Text = 'Import and analyze Excel files automatically, with PDF visualization and report.';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Tooltip = {'HELP'};
            app.Button.Text = '?';
            app.v10AndreiMihaiNecsulea2025Label = uilabel(app.UIFigure);
            app.v10AndreiMihaiNecsulea2025Label.Text = 'v1.0 – Andrei-Mihai Necsulea, 2025';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end